classdef urban_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        URBANUTMUIFigure           matlab.ui.Figure
        FileMenu                   matlab.ui.container.Menu
        Menu                       matlab.ui.container.Menu
        GridLayout                 matlab.ui.container.GridLayout
        LeftPanel                  matlab.ui.container.Panel
        TabGroup                   matlab.ui.container.TabGroup
        UASTab                     matlab.ui.container.Tab
        btn_addUAS                 matlab.ui.control.Button
        EditAgentFunctionButton    matlab.ui.control.Button
        UASConfigurationLabel      matlab.ui.control.Label
        txt_UASHelp                matlab.ui.control.TextArea
        StreamConfigurationPanel   matlab.ui.container.Panel
        tbl_UAS                    matlab.ui.control.Table
        AddtomixDropDownLabel      matlab.ui.control.Label
        AddtomixDropDown           matlab.ui.control.DropDown
        btn_removeUAS              matlab.ui.control.Button
        FiniteConfigurationPanel   matlab.ui.container.Panel
        tbl_UAS_finite_config      matlab.ui.control.Table
        AddtomixDropDown_2Label    matlab.ui.control.Label
        AddtomixFiniteDropDown     matlab.ui.control.DropDown
        btn_removeUASFinite        matlab.ui.control.Button
        UTMTab                     matlab.ui.container.Tab
        UTMClassDropDownLabel      matlab.ui.control.Label
        UTMClassDropDown           matlab.ui.control.DropDown
        EditUTMClassButton         matlab.ui.control.Button
        btn_refreshUTM             matlab.ui.control.Button
        tbl_UTMProps               matlab.ui.control.Table
        tbl_UTMMethods             matlab.ui.control.Table
        UTMPropertiesLabel         matlab.ui.control.Label
        UTMMethodsLabel            matlab.ui.control.Label
        txt_UTMPropHelp            matlab.ui.control.TextArea
        txt_UTMMethodHelp          matlab.ui.control.TextArea
        btn_addUTM                 matlab.ui.control.Button
        LanesTab                   matlab.ui.container.Tab
        tbl_Lanes                  matlab.ui.control.Table
        LanesLabel                 matlab.ui.control.Label
        btn_addLane                matlab.ui.control.Button
        btn_delLane                matlab.ui.control.Button
        tbl_Nodes                  matlab.ui.control.Table
        NodesLabel                 matlab.ui.control.Label
        btn_lanesEditMode          matlab.ui.control.StateButton
        LanesFileDropDownLabel     matlab.ui.control.Label
        LanesFileDropDown          matlab.ui.control.DropDown
        PlotModeButtonGroup        matlab.ui.container.ButtonGroup
        QuiverButton               matlab.ui.control.RadioButton
        DigraphButton              matlab.ui.control.RadioButton
        SaveLanesButton            matlab.ui.control.Button
        SaveAsButton               matlab.ui.control.Button
        NewLanesButton             matlab.ui.control.Button
        PerceptsTab                matlab.ui.container.Tab
        PerceptClassDropDownLabel  matlab.ui.control.Label
        PerceptClassDropDown       matlab.ui.control.DropDown
        btn_addPercept             matlab.ui.control.Button
        EditPerceptClassButton     matlab.ui.control.Button
        btn_refreshUAS_2           matlab.ui.control.Button
        tbl_PerceptProps           matlab.ui.control.Table
        tbl_PerceptMethods         matlab.ui.control.Table
        PerceptPropertiesLabel     matlab.ui.control.Label
        PerceptMethodsLabel        matlab.ui.control.Label
        txt_PerceptPropHelp        matlab.ui.control.TextArea
        txt_PerceptMethodHelp      matlab.ui.control.TextArea
        ActionsTab                 matlab.ui.container.Tab
        ActionClassDropDownLabel   matlab.ui.control.Label
        ActionClassDropDown        matlab.ui.control.DropDown
        btn_addAction              matlab.ui.control.Button
        tbl_ActionProps            matlab.ui.control.Table
        tbl_ActionMethods          matlab.ui.control.Table
        ActionPropertiesLabel_2    matlab.ui.control.Label
        ActionMethodsLabel         matlab.ui.control.Label
        btn_EditActionClass        matlab.ui.control.Button
        btn_refreshActionClasses   matlab.ui.control.Button
        txt_ActionPropHelp         matlab.ui.control.TextArea
        txt_ActionMethodHelp       matlab.ui.control.TextArea
        CenterPanel                matlab.ui.container.Panel
        UIAxes                     matlab.ui.control.UIAxes
        RightPanel                 matlab.ui.container.Panel
        SimulationParametersPanel  matlab.ui.container.Panel
        UTMTypeEditFieldLabel      matlab.ui.control.Label
        UTMTypeEditField           matlab.ui.control.EditField
        PerceptTypeEditFieldLabel  matlab.ui.control.Label
        PerceptTypeEditField       matlab.ui.control.EditField
        ActionTypeEditFieldLabel   matlab.ui.control.Label
        ActionTypeEditField        matlab.ui.control.EditField
        SimulationControlPanel     matlab.ui.container.Panel
        RunButton                  matlab.ui.control.StateButton
        InitializeButton           matlab.ui.control.Button
        StepsEditFieldLabel        matlab.ui.control.Label
        StepsEditField             matlab.ui.control.NumericEditField
        StepLabel                  matlab.ui.control.Label
        StepEditField              matlab.ui.control.NumericEditField
        TimeLabel                  matlab.ui.control.Label
        TimeEditField_h            matlab.ui.control.NumericEditField
        TimeEditField_m            matlab.ui.control.NumericEditField
        TimeEditField_s            matlab.ui.control.NumericEditField
        hLabel                     matlab.ui.control.Label
        mLabel                     matlab.ui.control.Label
        sLabel                     matlab.ui.control.Label
        Label                      matlab.ui.control.Label
        Lamp                       matlab.ui.control.Lamp
        ExportSimRunnerButton      matlab.ui.control.Button
        SeedEditFieldLabel         matlab.ui.control.Label
        SeedEditField              matlab.ui.control.NumericEditField
        SimDelaysSliderLabel       matlab.ui.control.Label
        SimDelaysSlider            matlab.ui.control.Slider
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = public)
        m_agent_files
        
        % UTM Properties
        m_utm_selected
        m_utm_prop_selected
        m_utm_method_selected
        
        % UAS Properties
        m_uas_selected
        m_uas_prop_selected
        m_uas_inds_selected
        
        % Percept Properties
        m_percept_selected
        m_percept_prop_selected
        m_percept_method_selected
        
        % Action Properties
        m_action_selected
        m_action_prop_selected
        m_action_method_selected
        
        % Lane Properties
        m_lane_file_selected
        m_lane_sys
        m_highlighted_lane_ids
        m_highlighted_node_ids
        m_lane_inds_selected;
        
        % Plot properties
        m_plot_type = 'Digraph'
        m_digraph_plot_h
        m_edit_mode
        
        % Simulator Properties
        m_initialized = false;
        m_sim_runner
        m_num_steps = 0;
        m_sim_delay = 0;
        
        m_template = 'agents/NormalUAS.m'
    end
    
    methods (Access = private)
        
        function updateLanesPlot(app, clear_first)
            if nargin < 2
                clear_first = false;
            end
            
            if (~isempty(app.m_lane_sys))
                if clear_first
                    app.clearPlot();
                end
                
                if strcmp(app.m_plot_type,'Digraph')
                    app.m_digraph_plot_h = app.m_lane_sys.plotLanesDigraph(app.UIAxes, @app.handleDigraphClick);
                    if app.m_edit_mode
                        app.enableEditMode();
                    end
                    if ~isempty(app.m_highlighted_lane_ids)
                        app.m_lane_sys.highlightLanes(app.m_digraph_plot_h, app.m_highlighted_lane_ids);
                    end
                    if ~isempty(app.m_highlighted_node_ids)
                        app.m_lane_sys.highlightNodes(app.m_digraph_plot_h, app.m_highlighted_node_ids);
                    end
                elseif strcmp(app.m_plot_type,'Quiver')
                    app.m_lane_sys.plotLanesQuiver(app.UIAxes);
                else
                    warning("Radio btn doesn't match a known plot type");
                    app.m_lane_sys.plotLanesQuiver(app.UIAxes);
                end
            end
            %axis(app.UIAxes, 'equal');
        end
        
        function resetHighlighted(app)
            app.m_highlighted_lane_ids = {};
            app.m_highlighted_node_ids = {};
        end
        
        function handleDigraphClick(app, src, eventData)
            xp = src.XData - eventData.IntersectionPoint(1);
            yp = src.YData - eventData.IntersectionPoint(2);
            % Find the closest node
            d = abs(xp) + abs(yp);
            [~,i] = min(d);
            node_id = src.NodeLabel{i};
        end
        
        function clearPlot(app)
            cla(app.UIAxes);
        end
        
        function loadLaneFile(app)
            if (~isempty(app.m_lane_file_selected))
                l = load(['lanes/' app.m_lane_file_selected '.mat']);
                app.m_lane_sys = l.lanes;
                if isfield(l, 'nodes')
                    app.tbl_Nodes.Data = l.nodes;
                end
                if ~isempty(app.m_lane_sys)
                    app.validateSaveLane();
                end
                app.resetHighlighted();
            end
        end
        
        function saveLaneFile(app, file_name, is_new_lanes)
            if ~is_new_lanes
                lanes = app.m_lane_sys.cloneLanes();
                if ~isempty(app.tbl_Nodes.Data)
                    nodes = app.tbl_Nodes.Data;
                    save(['lanes/' file_name], 'lanes', 'nodes');
                else
                    save(['lanes/' file_name], 'lanes');
                end
            else
                lanes = app.m_lane_sys;
                save(['lanes/' file_name], 'lanes');
            end
            app.validateSaveLane();
        end
        
        function clearLaneSys(app)
            app.m_lane_sys = [];
            app.updateLanesTbl();
            app.updateNodesTbl();
            app.clearPlot();
            app.invalidateSaveLane();
        end
        
        function updateLanesTbl(app)
            num_lanes = length(app.m_lane_sys);
            lanes = cell(num_lanes,3);
            for i = 1:num_lanes
                lanes{i,1} = app.m_lane_sys(i).m_id;
                x0 = [num2str(app.m_lane_sys(i).m_x0(1)) ', ' num2str(app.m_lane_sys(i).m_x0(2))];
                xf = [num2str(app.m_lane_sys(i).m_xf(1)) ', ' num2str(app.m_lane_sys(i).m_xf(2))];
                lanes{i,2} = x0;
                lanes{i,3} = xf;
            end
            tdata = cell2table(lanes, 'VariableNames', {'ID','x0','xf'});
            app.tbl_Lanes.Data = tdata;
        end
        
        function updateNodesTbl(app)
            if ~isempty(app.m_lane_sys)
                node_names = app.m_lane_sys.getDigraph().Nodes.Name;
                %num_nodes = length(node_names);
                node_table = app.tbl_Nodes.Data;
                new_node_table = table(node_names,'VariableNames',{'ID'});
                
                if (~isempty(node_table))
                    tmp_tbl = outerjoin(node_table, new_node_table , 'Keys', 'ID', 'Type', 'right', 'MergeKeys',1);
                    % Fix any dropped cells
                    if (~isempty(tmp_tbl(strcmp(tmp_tbl{:,2},''),2)))
                        tmp_tbl(strcmp(tmp_tbl{:,2},''),2) = {'normal'};
                    end
                    
                    if any(isundefined(tmp_tbl{:,{'Type'}}))
                        tmp_tbl(isundefined(tmp_tbl{:,{'Type'}}),{'Type'}) = {'normal'};
                    end
                else
                    digrph = app.m_lane_sys.getDigraph();
                    launch_nodes = (digrph.indegree == 0) & (digrph.outdegree == 1);
                    land_nodes = (digrph.indegree == 1) & (digrph.outdegree == 0);
                    node_types = categorical({'normal'},{'normal','land','launch'});
                    node_type_column = repmat(node_types,size(new_node_table,1),1);
                    node_type_column(launch_nodes) = ...
                        repmat(categorical({'launch'},{'normal','land','launch'}), sum(launch_nodes), 1);
                    node_type_column(land_nodes) = ...
                        repmat(categorical({'land'},{'normal','land','launch'}), sum(land_nodes), 1);
                    tmp_tbl = table(node_names, node_type_column, 'VariableNames', {'ID', 'Type'});
                end
                app.tbl_Nodes.Data = tmp_tbl;
            else
                app.tbl_Nodes.Data = [];
            end
            %app.tbl_Nodes.Data.Properties.RowNames = tdata{:,{'ID'}};
        end
        
        function updateUASTbl(app)
%             app.m_agent_files = dir('agents/*.m');
%             num_agent_types = length(app.m_agent_files);
%             agent_names = {app.m_agent_files.name}';
%             cats = categorical(agent_names);
%             count = zeros(num_agent_types, 1);
%             tdata = table(cats,count,'VariableNames',{'UAS_Type','Count'});
%             app.tbl_UAS.Data = tdata;
        end
        
        function updateUASTypesDropdown(app)
            app.m_agent_files = dir('agents/*.m');
            num_agent_types = length(app.m_agent_files);
            agent_names = {app.m_agent_files.name}';
            
            for i = 1:length(agent_names)
                agent_names{i} = strrep(agent_names{i},'.m','');
            end

            app.AddtomixDropDown.Items = agent_names;
            app.AddtomixDropDown.Items = [app.AddtomixDropDown.Items, {''}];
            app.AddtomixDropDown.Value = '';
            
            app.AddtomixFiniteDropDown.Items = agent_names;
            app.AddtomixFiniteDropDown.Items = [app.AddtomixFiniteDropDown.Items, {''}];
            app.AddtomixFiniteDropDown.Value = '';
        end
        
        function updateUASPropsTbl(app)
            app.tbl_UASProps.Enable = 'on';
            mc = meta.class.fromName(app.m_uas_selected);
            
            propNames = {};
            for i = 1:length(mc.PropertyList)
                propNames = [propNames; mc.PropertyList(i).Name];
            end
            
            tdata = table(propNames,'VariableNames',{'Property'});
            app.tbl_UASProps.Data = tdata;
        end
        
        function updateUTMPropsTbl(app)
            app.tbl_UTMProps.Enable = 'on';
            mc = meta.class.fromName(app.m_utm_selected);
            
            propNames = {};
            for i = 1:length(mc.PropertyList)
                propNames = [propNames; mc.PropertyList(i).Name];
            end
            
            tdata = table(propNames,'VariableNames',{'Property'});
            app.tbl_UTMProps.Data = tdata;
        end
        
        function updateUTMMethodTbl(app)
            app.tbl_UTMMethods.Enable = 'on';
            mc = meta.class.fromName(app.m_utm_selected);
            
            methodNames = {};
            for i = 1:length(mc.PropertyList)
                methodNames = [methodNames; mc.MethodList(i).Name];
            end
            
            tdata = table(methodNames,'VariableNames',{'Method'});
            app.tbl_UTMMethods.Data = tdata;
        end 
        
        function updatePerceptDropdown(app, reset_selection)
            percept_files = dir('percepts/*.m');
            percept_names = {percept_files.name}';
            for i = 1:length(percept_names)
                percept_names{i} = strrep(percept_names{i},'.m','');
            end

            app.PerceptClassDropDown.Items = percept_names;
            app.PerceptClassDropDown.Value = percept_names{1};
            if (reset_selection)
                app.m_percept_selected = percept_names{1};
            end
        end
        
        function updatePerceptPropsTbl(app)
            app.tbl_PerceptProps.Enable = 'on';
            mc = meta.class.fromName(app.m_percept_selected);
            
            propNames = {};
            for i = 1:length(mc.PropertyList)
                propNames = [propNames; mc.PropertyList(i).Name];
            end
            
            tdata = table(propNames,'VariableNames',{'Property'});
            app.tbl_PerceptProps.Data = tdata;
        end
        
        function updatePerceptMethodTbl(app)
            app.tbl_PerceptMethods.Enable = 'on';
            mc = meta.class.fromName(app.m_percept_selected);
            
            methodNames = {};
            for i = 1:length(mc.PropertyList)
                methodNames = [methodNames; mc.MethodList(i).Name];
            end
            
            tdata = table(methodNames,'VariableNames',{'Method'});
            app.tbl_PerceptMethods.Data = tdata;
        end
        
        function updateLanesDropdown(app, reset_selection)
            lane_files = dir('lanes/*.mat');
            lane_names = {lane_files.name}';
            for i = 1:length(lane_names)
                lane_names{i} = strrep(lane_names{i},'.mat','');
            end

            if isempty(lane_names)
                lane_names = {''};
            end
            app.LanesFileDropDown.Items = lane_names;
            app.LanesFileDropDown.Value = lane_names{1};
            if (reset_selection)
                app.m_lane_file_selected = lane_names{1};
            end
        end
        
        function updateSelectedLanesFile(app, name)
            lane_files = dir('lanes/*.mat');
            lane_names = {lane_files.name}';
            selected_name = [];
            for i = 1:length(lane_names)
                if (contains(lane_names(i), name))
                    selected_name = strrep(lane_names{i},'.mat','');
                    break;
                end
            end
            
            if (~isempty(selected_name))
                app.LanesFileDropDown.Value = selected_name;
                app.m_lane_file_selected = selected_name;
            else
                warning(['Lanes file ' name ' could not be located']);
            end
        end
 
        function updateActionDropdown(app, reset_selection)
            action_files = dir('actions/*.m');
            action_names = {action_files.name}';
            for i = 1:length(action_names)
                action_names{i} = strrep(action_names{i},'.m','');
            end

            app.ActionClassDropDown.Items = action_names;
            app.ActionClassDropDown.Value = action_names{1};
            if (reset_selection)
                app.m_action_selected = action_names{1};
            end
        end
        
        function updateActionPropsTbl(app)
            app.tbl_ActionProps.Enable = 'on';
            mc = meta.class.fromName(app.m_action_selected);
            
            propNames = {};
            for i = 1:length(mc.PropertyList)
                propNames = [propNames; mc.PropertyList(i).Name];
            end
            
            tdata = table(propNames,'VariableNames',{'Property'});
            app.tbl_ActionProps.Data = tdata;
        end
        
        function updateActionMethodTbl(app)
            app.tbl_ActionMethods.Enable = 'on';
            mc = meta.class.fromName(app.m_action_selected);
            
            methodNames = {};
            for i = 1:length(mc.PropertyList)
                methodNames = [methodNames; mc.MethodList(i).Name];
            end
            
            tdata = table(methodNames,'VariableNames',{'Method'});
            app.tbl_ActionMethods.Data = tdata;
        end        
        
        function updateUTMDropdown(app, reset_selection)
            utm_files = dir('utms/*.m');
            utm_names = {utm_files.name}';
            for i = 1:length(utm_names)
                utm_names{i} = strrep(utm_names{i},'.m','');
            end

            app.UTMClassDropDown.Items = utm_names;
            app.UTMClassDropDown.Value = utm_names{1};
            if (reset_selection)
                app.m_utm_selected = utm_names{1};
            end
        end
        
        function updatePanelUTM(app)
            app.UTMTypeEditField.Value = app.m_utm_selected;
        end
        
        function updatePanelAction(app)
            app.ActionTypeEditField.Value = app.m_action_selected;
        end
        
        function updatePanelPercept(app)
            app.PerceptTypeEditField.Value = app.m_percept_selected;
        end
        
        function handleLaneNodeChanged(app, ~, ~)
            app.updateLanesPlot();
            app.updateLanesTbl();
            app.updateNodesTbl();
            app.invalidateSaveLane();
        end
        
        function enableEditMode(app)         
            for i = 1:length(app.m_lane_sys)
                l = app.m_lane_sys(i);
                
                position = [l.m_x0(1) l.m_x0(2); l.m_xf(1) l.m_xf(2)];
                roi = drawline(app.UIAxes, 'Position', position);
                delete(l.m_roi);
                l.m_roi = roi;
                %delete(l.m_listeners);
                e1 = addlistener(roi,'MovingROI',@l.handleRoiMove);
                e2 = addlistener(roi,'ROIMoved',@l.handleRoiMove);
                e3 = addlistener(l, 'NodeChanged', @app.handleLaneNodeChanged);
                if isempty(l.m_listeners)
                    l.m_listeners = [e1 e2 e3];
                else
                    delete(l.m_listeners);
                    l.m_listeners(3) = e1;
                    l.m_listeners(2) = e2;
                    l.m_listeners(1) = e3;
                end
            end
        end
        
        function row = createUASTableRow(app, uas_type)
            time_dist = categorical({'constant'},{'constant';'poisson'});
            start_dist = categorical({'round-robin'},{'round-robin';'uniform'});
            
            start_nodes_c = app.tbl_Nodes.Data{app.tbl_Nodes.Data{:,{'Type'}} == 'launch', 'ID'};
            start_nodes = strjoin(start_nodes_c',',');
            
            row = table({uas_type},{'0,0,0'},time_dist,5.0,start_dist,{start_nodes},...
                'VariableNames',{'UAS_Type','Color','Time Dist.', 'Period', 'Start Dist.', 'Start Nodes'});
        end
        
        function row = createFiniteUASTableRow(app, uas_type)       
            if ~isempty(app.m_lane_sys)
                lane = {app.m_lane_sys(1).m_id};
            else
                lane = {''};
            end
            
            row = table({uas_type},{'0,0,0'},1,lane,...
                'VariableNames',{'UAS_Type','Color','Start Step', 'Start Lane'});
        end
        
        function addRowToUASTbl(app, uas_type)
            new_row = app.createUASTableRow(uas_type);
            app.tbl_UAS.Data = [app.tbl_UAS.Data; new_row];
        end
        
        function addRowToUASTblFinite(app, uas_type)
            new_row = app.createFiniteUASTableRow(uas_type);
            app.tbl_UAS_finite_config.Data = [app.tbl_UAS_finite_config.Data; new_row];
        end
        
        function handleSimRunnerStep(app,src,evt)
            step = src.m_time_step;
            seconds = step*src.m_sample_per;
            hours = floor(seconds/3600);
            minutes = floor(seconds/60) - hours*60;
            seconds = seconds - (hours*3600) - (minutes*60);
            app.StepEditField.Value = step;
            app.TimeEditField_h.Value = hours;
            app.TimeEditField_m.Value = minutes;
            app.TimeEditField_s.Value = seconds;
            drawnow limitrate
        end
        
        function invalidateSaveLane(app)
            app.SaveLanesButton.FontColor = [1 0 0];
        end
        
        function validateSaveLane(app)
            app.SaveLanesButton.FontColor = [0 1 0];
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Update UAS Tab
            app.updateUASTbl();
            app.updateUASTypesDropdown();
            app.EditAgentFunctionButton.Enable = false;
            
            % Update UTM Tab
            app.updateUTMDropdown(true);
            app.updateUTMPropsTbl();
            app.updateUTMMethodTbl();
            
            % Initialize Percept Tab
            app.updatePerceptDropdown(true);
            app.updatePerceptPropsTbl();
            app.updatePerceptMethodTbl();
            
            % Initialize Action Tab
            app.updateActionDropdown(true);
            app.updateActionPropsTbl();
            app.updateActionMethodTbl();
            
            % Initialize the lanes
            app.updateLanesDropdown(true);
            app.loadLaneFile();
            app.updateLanesTbl();
            app.updateNodesTbl();
            
            % Initialize the summary panel
            app.updatePanelUTM();
            app.updatePanelAction();
            app.updatePanelPercept();
            
            % Update the plot
            app.updateLanesPlot();
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.URBANUTMUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {804, 804, 804};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {804, 804};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {332, '1x', 270};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end

        % Callback function
        function ButtonPushed(app, event)
            roi = drawline(app.UIAxes);
        end

        % Button pushed function: btn_addUAS
        function btn_addUASButtonPushed(app, event)
            prompt = {'Enter Agent Type Name:'};
            dlgtitle = 'Input';
            dims = [1 35];
            definput = {'MyUAS'};
            answer = inputdlg(prompt,dlgtitle,dims,definput);
            if (~isempty(answer))
                agent_name = answer{1};
                agent_file_name = ['agents/' agent_name '.m'];
                %copyfile(app.m_template, agent_file_name);
                fid  = fopen(app.m_template,'r');
                f = fread(fid,'*char')';
                fclose(fid);
                f = strrep(f,'NormalUAS', agent_name);
                f = strrep(f,'MultiLaneUAS', 'NormalUAS');
                fid  = fopen(agent_file_name,'w');
                fprintf(fid,'%s',f);
                fclose(fid);
                app.updateUASTypesDropdown();
                %app.addRowToUASTbl(agent_name);
            end
        end

        % Cell selection callback: tbl_UAS
        function tbl_UASCellSelection(app, event)
            indices = event.Indices;
            app.m_uas_inds_selected = indices;
            c = cellstr(app.tbl_UAS.Data(indices(1),1).UAS_Type);
            app.m_uas_selected = strrep(c{1},'.m','');
            app.EditAgentFunctionButton.Enable = true;
            %app.updateUASPropsTbl();
            
            % Update the Help text
            help_text = help(app.m_uas_selected);
            % Remove newlines
            %help_text = regexprep(help_text,'[\n\r]+','');
            % = regexprep(help_text,'[^\S\r\n]{2,}','');
            help_text = regexprep(help_text,'[ \t]{2,}','');
            help_text = regexprep(help_text,'[\n\r]','');
            % Trim the text
            help_text = strtrim(help_text);
            % Update the text
            app.txt_UASHelp.Value = help_text;
        end

        % Button pushed function: EditAgentFunctionButton
        function EditAgentFunctionButtonPushed(app, event)
            if(~isempty(app.m_uas_selected))
                open([app.m_uas_selected '.m']);
            else
                uialert(app.URBANUTMUIFigure,'You must select a UAS type from the mix to edit','Missing Selection');
            end
        end

        % Callback function
        function btn_refreshUASButtonPushed(app, event)
            app.updateUASTbl();
        end

        % Value changed function: PerceptClassDropDown
        function PerceptClassDropDownValueChanged(app, event)
            value = app.PerceptClassDropDown.Value;
            app.m_percept_selected = value;
            app.updatePanelPercept();
            app.updatePerceptPropsTbl();
            app.updatePerceptMethodsTbl();
        end

        % Button pushed function: EditPerceptClassButton
        function EditPerceptClassButtonPushed(app, event)
            open(['percepts/' app.m_percept_selected '.m']);
        end

        % Button pushed function: btn_EditActionClass
        function btn_EditActionClassButtonPushed(app, event)
            open(['actions/' app.m_action_selected '.m']);
        end

        % Cell selection callback: tbl_PerceptProps
        function tbl_PerceptPropsCellSelection(app, event)
            indices = event.Indices;
            c = cellstr(app.tbl_PerceptProps.Data(indices(1),1).Property);
            app.m_percept_prop_selected = strrep(c{1},'.m','');
        
            % Update the Help text
            help_text = help([app.m_percept_selected '.' app.m_percept_prop_selected]);
            % Remove newlines
            %help_text = regexprep(help_text,'[\n\r]+','');
            % = regexprep(help_text,'[^\S\r\n]{2,}','');
            help_text = regexprep(help_text,'[ \t]{2,}','');
            help_text = regexprep(help_text,'[\n\r]','');
            % Trim the text
            help_text = strtrim(help_text);
            % Update the text
            app.txt_PerceptPropHelp.Value = help_text;
        end

        % Cell selection callback: tbl_PerceptMethods
        function tbl_PerceptMethodsCellSelection(app, event)
            indices = event.Indices;
            c = cellstr(app.tbl_PerceptMethods.Data(indices(1),1).Method);
            app.m_percept_method_selected = strrep(c{1},'.m','');
        
            % Update the Help text
            help_text = help([app.m_percept_selected '.' app.m_percept_method_selected]);
            % Remove newlines
            %help_text = regexprep(help_text,'[\n\r]+','');
            % = regexprep(help_text,'[^\S\r\n]{2,}','');
            help_text = regexprep(help_text,'[ \t]{2,}','');
            help_text = regexprep(help_text,'[\n\r]','');
            % Trim the text
            help_text = strtrim(help_text);
            % Update the text
            app.txt_PerceptMethodHelp.Value = help_text;
        end

        % Cell selection callback: tbl_ActionProps
        function tbl_ActionPropsCellSelection(app, event)
            indices = event.Indices;
            c = cellstr(app.tbl_ActionProps.Data(indices(1),1).Property);
            app.m_action_prop_selected = strrep(c{1},'.m','');
        
            % Update the Help text
            help_text = help([app.m_action_selected '.' app.m_action_prop_selected]);
            % Remove newlines
            %help_text = regexprep(help_text,'[\n\r]+','');
            % = regexprep(help_text,'[^\S\r\n]{2,}','');
            help_text = regexprep(help_text,'[ \t]{2,}','');
            help_text = regexprep(help_text,'[\n\r]','');
            % Trim the text
            help_text = strtrim(help_text);
            % Update the text
            app.txt_ActionPropHelp.Value = help_text;
        end

        % Cell selection callback: tbl_ActionMethods
        function tbl_ActionMethodsCellSelection(app, event)
            indices = event.Indices;
            c = cellstr(app.tbl_ActionMethods.Data(indices(1),1).Method);
            app.m_action_method_selected = strrep(c{1},'.m','');
        
            % Update the Help text
            help_text = help([app.m_action_selected '.' app.m_action_method_selected]);
            % Remove newlines
            %help_text = regexprep(help_text,'[\n\r]+','');
            % = regexprep(help_text,'[^\S\r\n]{2,}','');
            help_text = regexprep(help_text,'[ \t]{2,}','');
            help_text = regexprep(help_text,'[\n\r]','');
            % Trim the text
            help_text = strtrim(help_text);
            % Update the text
            app.txt_ActionMethodHelp.Value = help_text;
        end

        % Callback function
        function tbl_UASPropsCellSelection(app, event)
            indices = event.Indices;
            c = cellstr(app.tbl_UASProps.Data(indices(1),1).Property);
            app.m_uas_prop_selected = strrep(c{1},'.m','');
        
            % Update the Help text
            help_text = help([app.m_uas_selected '.' app.m_uas_prop_selected]);
            % Remove newlines
            %help_text = regexprep(help_text,'[\n\r]+','');
            % = regexprep(help_text,'[^\S\r\n]{2,}','');
            help_text = regexprep(help_text,'[ \t]{2,}','');
            help_text = regexprep(help_text,'[\n\r]','');
            % Trim the text
            help_text = strtrim(help_text);
            % Update the text
            app.txt_UASPropHelp.Value = help_text;
            
        end

        % Value changed function: UTMClassDropDown
        function UTMClassDropDownValueChanged(app, event)
            value = app.UTMClassDropDown.Value;
            app.m_utm_selected = value;
            app.updatePanelUTM();
            app.updateUTMPropsTbl();
            app.updateUTMMethodsTbl();
        end

        % Button pushed function: EditUTMClassButton
        function EditUTMClassButtonPushed(app, event)
            open(['utms/' app.m_utm_selected '.m']);
        end

        % Cell selection callback: tbl_UTMProps
        function tbl_UTMPropsCellSelection(app, event)
            indices = event.Indices;
            c = cellstr(app.tbl_UTMProps.Data(indices(1),1).Property);
            app.m_utm_prop_selected = strrep(c{1},'.m','');
        
            % Update the Help text
            help_text = help([app.m_utm_selected '.' app.m_utm_prop_selected]);
            % Remove newlines
            %help_text = regexprep(help_text,'[\n\r]+','');
            % = regexprep(help_text,'[^\S\r\n]{2,}','');
            help_text = regexprep(help_text,'[ \t]{2,}','');
            help_text = regexprep(help_text,'[\n\r]','');
            % Trim the text
            help_text = strtrim(help_text);
            % Update the text
            app.txt_UTMPropHelp.Value = help_text;
        end

        % Cell selection callback: tbl_UTMMethods
        function tbl_UTMMethodsCellSelection(app, event)
            indices = event.Indices;
            c = cellstr(app.tbl_UTMMethods.Data(indices(1),1).Method);
            app.m_utm_method_selected = strrep(c{1},'.m','');
        
            % Update the Help text
            help_text = help([app.m_utm_selected '.' app.m_utm_method_selected]);
            % Remove newlines
            %help_text = regexprep(help_text,'[\n\r]+','');
            % = regexprep(help_text,'[^\S\r\n]{2,}','');
            help_text = regexprep(help_text,'[ \t]{2,}','');
            help_text = regexprep(help_text,'[\n\r]','');
            % Trim the text
            help_text = strtrim(help_text);
            % Update the text
            app.txt_UTMMethodHelp.Value = help_text;
        end

        % Value changed function: ActionClassDropDown
        function ActionClassDropDownValueChanged(app, event)
            value = app.ActionClassDropDown.Value;
            app.m_action_selected = value;
            app.updatePanelAction();
            app.updateActionPropsTbl();
            app.updateActionMethodsTbl();
        end

        % Selection changed function: PlotModeButtonGroup
        function PlotModeButtonGroupSelectionChanged(app, event)
            selectedButton = app.PlotModeButtonGroup.SelectedObject;
            app.m_plot_type = selectedButton.Text;
            app.clearPlot();
            app.updateLanesPlot();
        end

        % Cell selection callback: tbl_Lanes
        function tbl_LanesCellSelection(app, event)
            indices = event.Indices;
            app.m_lane_inds_selected = indices;
            id = app.tbl_Lanes.Data(indices(1),1).ID;
            app.m_highlighted_lane_ids = id;
            app.updateLanesPlot();
        end

        % Cell selection callback: tbl_Nodes
        function tbl_NodesCellSelection(app, event)
            indices = event.Indices;
            %app.tbl_Nodes.BackgroundColor();
            app.m_highlighted_node_ids = app.tbl_Nodes.Data(indices(:,1),1).ID;
            app.updateLanesPlot();
        end

        % Value changed function: btn_lanesEditMode
        function btn_lanesEditModeValueChanged(app, event)
            app.m_edit_mode = app.btn_lanesEditMode.Value;
            app.updateLanesPlot();
        end

        % Value changed function: AddtomixDropDown
        function AddtomixDropDownValueChanged(app, event)
            value = app.AddtomixDropDown.Value;
            app.addRowToUASTbl(value);
        end

        % Button pushed function: btn_removeUAS
        function btn_removeUASButtonPushed(app, event)
            if (~isempty(app.m_uas_inds_selected))
                uas_to_remove = app.tbl_UAS.Data{app.m_uas_inds_selected(:,1),1};
                prompt = {['Delete ' num2str(length(uas_to_remove)) ' UAS from Mix?']};
                selection = uiconfirm(app.URBANUTMUIFigure,prompt,'Confirm Removal','Icon','warning');
                if (strcmp(selection, 'OK'))
                    app.tbl_UAS.Data(app.m_uas_inds_selected(:,1),:) = [];
                end
            end
        end

        % Button pushed function: InitializeButton
        function InitializeButtonPushed(app, event)
            if ~isempty(app.m_sim_runner) && isvalid(app.m_sim_runner)
                app.m_sim_runner.deletePlot();
            end
            app.m_sim_runner = MultiLaneSimRunner();
            
            app.m_sim_runner.initialize_from_gui(...
                  app.m_utm_selected...     % The selected UTM class name
                , app.tbl_UAS.Data...       % The table containing the desired mix of UAS types
                , app.tbl_UAS_finite_config.Data...% The table containing the desired mix of UAS types
                , app.m_percept_selected... % The selected percept class
                , app.m_action_selected...  % The selected action class
                , app.m_lane_sys...         % The lane system
                , app.tbl_Nodes.Data...     % The node configuration table
            )
            
            app.m_initialized = true;
            app.Lamp.Color = [0.00,1.00,0.00];
            app.StepsEditField.Enable = true;
            app.RunButton.Enable = true;
            app.ExportSimRunnerButton.Enable = true;
            app.handleSimRunnerStep(app.m_sim_runner);
            app.SeedEditField.Enable = true;
            app.SeedEditField.Value = double(app.m_sim_runner.getSeed());
            app.SimDelaysSlider.Enable = true;
            app.m_sim_runner.set_delay(app.m_sim_delay);
        end

        % Value changed function: StepsEditField
        function StepsEditFieldValueChanged(app, event)
            value = app.StepsEditField.Value;
            app.m_num_steps = value;
        end

        % Value changed function: RunButton
        function RunButtonValueChanged(app, event)
            value = app.RunButton.Value;
            if (value)
                app.m_sim_runner.enablePlotSteps(app.UIAxes);
                l = addlistener(app.m_sim_runner, 'StepTaken', @app.handleSimRunnerStep);
                app.m_sim_runner.runSim(app.m_num_steps);
                delete(l);
                app.RunButton.Value = false;
                app.m_sim_runner.disablePlotSteps();
            end
        end

        % Button pushed function: ExportSimRunnerButton
        function ExportSimRunnerButtonPushed(app, event)
            sim_runner = app.m_sim_runner;
            assignin("base", "sim_runner", sim_runner);
        end

        % Value changed function: AddtomixFiniteDropDown
        function AddtomixFiniteDropDownValueChanged(app, event)
            value = app.AddtomixFiniteDropDown.Value;
            app.addRowToUASTblFinite(value);
        end

        % Cell selection callback: tbl_UAS_finite_config
        function tbl_UAS_finite_configCellSelection(app, event)
            indices = event.Indices;
            app.m_uas_inds_selected = indices;
            c = cellstr(app.tbl_UAS_finite_config.Data(indices(1),1).UAS_Type);
            app.m_uas_selected = strrep(c{1},'.m','');
            app.EditAgentFunctionButton.Enable = true;
            %app.updateUASPropsTbl();
            
            % Update the Help text
            help_text = help(app.m_uas_selected);
            % Remove newlines
            %help_text = regexprep(help_text,'[\n\r]+','');
            % = regexprep(help_text,'[^\S\r\n]{2,}','');
            help_text = regexprep(help_text,'[ \t]{2,}','');
            help_text = regexprep(help_text,'[\n\r]','');
            % Trim the text
            help_text = strtrim(help_text);
            % Update the text
            app.txt_UASHelp.Value = help_text;
        end

        % Button pushed function: btn_removeUASFinite
        function btn_removeUASFiniteButtonPushed(app, event)
            if (~isempty(app.m_uas_inds_selected))
                uas_to_remove = app.tbl_UAS_finite_config.Data{app.m_uas_inds_selected(:,1),1};
                prompt = {['Delete ' num2str(length(uas_to_remove)) ' UAS from Mix?']};
                selection = uiconfirm(app.URBANUTMUIFigure,prompt,'Confirm Removal','Icon','warning');
                if (strcmp(selection, 'OK'))
                    app.tbl_UAS_finite_config.Data(app.m_uas_inds_selected(:,1),:) = [];
                end
            end
        end

        % Value changed function: SeedEditField
        function SeedEditFieldValueChanged(app, event)
            value = app.SeedEditField.Value;
            app.m_sim_runner.setSeed(value);
        end

        % Value changed function: SimDelaysSlider
        function SimDelaysSliderValueChanged(app, event)
            value = app.SimDelaysSlider.Value;
            app.m_sim_runner.set_delay(value);
            app.m_sim_delay = value;
        end

        % Button pushed function: NewLanesButton
        function NewLanesButtonPushed(app, event)
            prompt = {'Enter New Lanes File Name:'};
            dlgtitle = 'Input';
            dims = [1 35];
            definput = {'MyLanes'};
            answer = inputdlg(prompt,dlgtitle,dims,definput);
            if (~isempty(answer))
                lanes_name = answer{1};
                app.clearLaneSys();
                app.saveLaneFile(lanes_name, true)
                app.updateLanesDropdown(false);
                app.updateSelectedLanesFile(lanes_name);
                app.loadLaneFile();
                app.updateLanesTbl();
                app.updateNodesTbl();
            end
        end

        % Button pushed function: SaveLanesButton
        function SaveLanesButtonPushed(app, event)
            app.saveLaneFile(app.m_lane_file_selected, false);
        end

        % Value changed function: LanesFileDropDown
        function LanesFileDropDownValueChanged(app, event)
            value = app.LanesFileDropDown.Value;
            app.m_lane_file_selected = value;
            app.loadLaneFile();
            app.updateLanesTbl();
            app.updateNodesTbl();
            app.updateLanesPlot(true);
        end

        % Button pushed function: btn_addLane
        function btn_addLaneButtonPushed(app, event)
            app.btn_lanesEditMode.Value = true;
            app.m_edit_mode = true;
            app.updateLanesPlot();
            l = Lane([0 0],[0 0]);
            l.setLaneID(length(app.m_lane_sys) + 1);
            l.setNodeIDs(l.getUniqueNodeId(), l.getUniqueNodeId());
            app.m_lane_sys = [app.m_lane_sys l];
            app.m_lane_sys.getDigraph();
            roi = drawline(app.UIAxes);
            l.m_roi = roi;
            %delete(l.m_listeners);
            evt.EventName = 'ROIMoved';
            evt.CurrentPosition = roi.Position;
            l.handleRoiMove(roi, evt);
            app.handleLaneNodeChanged();
            %e1 = addlistener(roi,'MovingROI',@l.handleRoiMove);
            %e2 = addlistener(roi,'ROIMoved',@l.handleRoiMove);
            %e3 = addlistener(l, 'NodeChanged', @app.handleLaneNodeChanged);
            %l.m_listeners = [e3];
        end

        % Display data changed function: tbl_Lanes
        function tbl_LanesDisplayDataChanged(app, event)
            %newDisplayData = app.tbl_Lanes.DisplayData;
            %app.invalidateSaveLane();
        end

        % Button pushed function: SaveAsButton
        function SaveAsButtonPushed(app, event)
            prompt = {'Enter New Lanes File Name:'};
            dlgtitle = 'Input';
            dims = [1 35];
            definput = {app.m_lane_file_selected};
            answer = inputdlg(prompt,dlgtitle,dims,definput);
            if (~isempty(answer))
                lanes_name = answer{1};
                app.saveLaneFile(lanes_name, false)
                app.updateLanesDropdown(false);
                app.updateSelectedLanesFile(lanes_name);
                app.loadLaneFile();
                app.updateLanesTbsl();
                app.updateNodesTbl();
            end
        end

        % Cell edit callback: tbl_Nodes
        function tbl_NodesCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            if (indices(2) == 1) % If the node id was changed
                old_name = event.PreviousData;
                app.m_lane_sys.updateNodeName(old_name, newData);
                app.resetHighlighted();
                app.updateNodesTbl();
                app.updateLanesTbl();
                app.updateLanesPlot(true);
            end
            app.invalidateSaveLane();
        end

        % Button pushed function: btn_delLane
        function btn_delLaneButtonPushed(app, event)
            if (~isempty(app.m_lane_inds_selected))
                lanes_to_remove = app.tbl_Lanes.Data{app.m_lane_inds_selected(:,1),1};
                prompt = {['Delete ' num2str(length(lanes_to_remove)) ' Lanes?']};
                selection = uiconfirm(app.URBANUTMUIFigure,prompt,'Confirm Removal','Icon','warning');
                if (strcmp(selection, 'OK'))
                    app.m_lane_sys(lanes_to_remove) = [];
                    app.m_lane_sys.getDigraph();
                    app.resetHighlighted();
                    app.updateLanesTbl();
                    app.updateNodesTbl();
                    app.updateLanesPlot(true);
                    app.invalidateSaveLane();
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.URBANUTMUIFigure = uifigure('Visible', 'off');
            app.URBANUTMUIFigure.Name = 'URBAN UTM';
            app.URBANUTMUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.FileMenu = uimenu(app.URBANUTMUIFigure);
            app.FileMenu.Text = 'File';
            app.Menu = uimenu(app.FileMenu);
            app.Menu.Text = 'Menu';
            app.GridLayout = uigridlayout(app.URBANUTMUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.LeftPanel);
            app.UASTab = uitab(app.TabGroup);
            app.UASTab.Title = 'UAS';
            app.btn_addUAS = uibutton(app.UASTab, 'push');
            app.btn_addUAS.ButtonPushedFcn = createCallbackFcn(app, @btn_addUASButtonPushed, true);
            app.btn_addUAS.Tooltip = {'Create a new agent'};
            app.btn_addUAS.Text = 'New UAS Type';
            app.EditAgentFunctionButton = uibutton(app.UASTab, 'push');
            app.EditAgentFunctionButton.ButtonPushedFcn = createCallbackFcn(app, @EditAgentFunctionButtonPushed, true);
            app.EditAgentFunctionButton.Text = 'Edit Agent Function';
            app.UASConfigurationLabel = uilabel(app.UASTab);
            app.UASConfigurationLabel.Text = 'UAS Configuration';
            app.txt_UASHelp = uitextarea(app.UASTab);
            app.txt_UASHelp.Editable = 'off';
            app.StreamConfigurationPanel = uipanel(app.UASTab);
            app.StreamConfigurationPanel.Title = 'Stream Configuration';
            app.tbl_UAS = uitable(app.StreamConfigurationPanel);
            app.tbl_UAS.ColumnName = {'UAS_Type'; 'Color'; 'Time Dist.'; 'Period'; 'Start Dist.'; 'Start Nodes'};
            app.tbl_UAS.RowName = {};
            app.tbl_UAS.ColumnSortable = [true true true true];
            app.tbl_UAS.ColumnEditable = [false true true true true true];
            app.tbl_UAS.CellSelectionCallback = createCallbackFcn(app, @tbl_UASCellSelection, true);
            app.AddtomixDropDownLabel = uilabel(app.StreamConfigurationPanel);
            app.AddtomixDropDownLabel.Text = 'Add to mix:';
            app.AddtomixDropDown = uidropdown(app.StreamConfigurationPanel);
            app.AddtomixDropDown.ValueChangedFcn = createCallbackFcn(app, @AddtomixDropDownValueChanged, true);
            app.btn_removeUAS = uibutton(app.StreamConfigurationPanel, 'push');
            app.btn_removeUAS.ButtonPushedFcn = createCallbackFcn(app, @btn_removeUASButtonPushed, true);
            app.btn_removeUAS.Tooltip = {'Remove UAS from the Mix'};
            app.btn_removeUAS.Text = '-';
            app.FiniteConfigurationPanel = uipanel(app.UASTab);
            app.FiniteConfigurationPanel.Title = 'Finite Configuration';
            app.tbl_UAS_finite_config = uitable(app.FiniteConfigurationPanel);
            app.tbl_UAS_finite_config.ColumnName = {'UAS_Type'; 'Color'; 'Start Step'; 'Start Lane'};
            app.tbl_UAS_finite_config.RowName = {};
            app.tbl_UAS_finite_config.ColumnSortable = [true true true true];
            app.tbl_UAS_finite_config.ColumnEditable = [false true true true true true];
            app.tbl_UAS_finite_config.CellSelectionCallback = createCallbackFcn(app, @tbl_UAS_finite_configCellSelection, true);
            app.AddtomixDropDown_2Label = uilabel(app.FiniteConfigurationPanel);
            app.AddtomixDropDown_2Label.Text = 'Add to mix:';
            app.AddtomixFiniteDropDown = uidropdown(app.FiniteConfigurationPanel);
            app.AddtomixFiniteDropDown.ValueChangedFcn = createCallbackFcn(app, @AddtomixFiniteDropDownValueChanged, true);
            app.btn_removeUASFinite = uibutton(app.FiniteConfigurationPanel, 'push');
            app.btn_removeUASFinite.ButtonPushedFcn = createCallbackFcn(app, @btn_removeUASFiniteButtonPushed, true);
            app.btn_removeUASFinite.Tooltip = {'Remove UAS from the Mix'};
            app.btn_removeUASFinite.Text = '-';
            app.UTMTab = uitab(app.TabGroup);
            app.UTMTab.Title = 'UTM';
            app.UTMClassDropDownLabel = uilabel(app.UTMTab);
            app.UTMClassDropDownLabel.Text = 'UTM Class';
            app.UTMClassDropDown = uidropdown(app.UTMTab);
            app.UTMClassDropDown.ValueChangedFcn = createCallbackFcn(app, @UTMClassDropDownValueChanged, true);
            app.EditUTMClassButton = uibutton(app.UTMTab, 'push');
            app.EditUTMClassButton.ButtonPushedFcn = createCallbackFcn(app, @EditUTMClassButtonPushed, true);
            app.EditUTMClassButton.Text = 'Edit UTM Class';
            app.btn_refreshUTM = uibutton(app.UTMTab, 'push');
            app.btn_refreshUTM.Icon = 'icons8-refresh-24.png';
            app.btn_refreshUTM.Tooltip = {'Refresh the content in this tab'};
            app.btn_refreshUTM.Text = '';
            app.tbl_UTMProps = uitable(app.UTMTab);
            app.tbl_UTMProps.ColumnName = {'Property'};
            app.tbl_UTMProps.RowName = {};
            app.tbl_UTMProps.CellSelectionCallback = createCallbackFcn(app, @tbl_UTMPropsCellSelection, true);
            app.tbl_UTMMethods = uitable(app.UTMTab);
            app.tbl_UTMMethods.ColumnName = {'Method'};
            app.tbl_UTMMethods.RowName = {};
            app.tbl_UTMMethods.CellSelectionCallback = createCallbackFcn(app, @tbl_UTMMethodsCellSelection, true);
            app.UTMPropertiesLabel = uilabel(app.UTMTab);
            app.UTMPropertiesLabel.Text = 'UTM Properties';
            app.UTMMethodsLabel = uilabel(app.UTMTab);
            app.UTMMethodsLabel.Text = 'UTM Methods';
            app.txt_UTMPropHelp = uitextarea(app.UTMTab);
            app.txt_UTMPropHelp.Editable = 'off';
            app.txt_UTMMethodHelp = uitextarea(app.UTMTab);
            app.txt_UTMMethodHelp.Editable = 'off';
            app.btn_addUTM = uibutton(app.UTMTab, 'push');
            app.btn_addUTM.Tooltip = {'Create a new agent'};
            app.btn_addUTM.Text = '+';
            app.LanesTab = uitab(app.TabGroup);
            app.LanesTab.Title = 'Lanes';
            app.tbl_Lanes = uitable(app.LanesTab);
            app.tbl_Lanes.ColumnName = {'ID'; 'x0'; 'xf'};
            app.tbl_Lanes.RowName = {};
            app.tbl_Lanes.ColumnSortable = true;
            app.tbl_Lanes.ColumnEditable = true;
            app.tbl_Lanes.CellSelectionCallback = createCallbackFcn(app, @tbl_LanesCellSelection, true);
            app.tbl_Lanes.DisplayDataChangedFcn = createCallbackFcn(app, @tbl_LanesDisplayDataChanged, true);
            app.LanesLabel = uilabel(app.LanesTab);
            app.LanesLabel.Text = 'Lanes';
            app.btn_addLane = uibutton(app.LanesTab, 'push');
            app.btn_addLane.ButtonPushedFcn = createCallbackFcn(app, @btn_addLaneButtonPushed, true);
            app.btn_addLane.Text = '+';
            app.btn_delLane = uibutton(app.LanesTab, 'push');
            app.btn_delLane.ButtonPushedFcn = createCallbackFcn(app, @btn_delLaneButtonPushed, true);
            app.btn_delLane.Text = '-';
            app.tbl_Nodes = uitable(app.LanesTab);
            app.tbl_Nodes.ColumnName = {'ID'; 'type'};
            app.tbl_Nodes.RowName = {};
            app.tbl_Nodes.ColumnSortable = [true true];
            app.tbl_Nodes.ColumnEditable = [true true];
            app.tbl_Nodes.CellEditCallback = createCallbackFcn(app, @tbl_NodesCellEdit, true);
            app.tbl_Nodes.CellSelectionCallback = createCallbackFcn(app, @tbl_NodesCellSelection, true);
            app.NodesLabel = uilabel(app.LanesTab);
            app.NodesLabel.Text = 'Nodes';
            app.btn_lanesEditMode = uibutton(app.LanesTab, 'state');
            app.btn_lanesEditMode.ValueChangedFcn = createCallbackFcn(app, @btn_lanesEditModeValueChanged, true);
            app.btn_lanesEditMode.Text = 'Edit Mode';
            app.LanesFileDropDownLabel = uilabel(app.LanesTab);
            app.LanesFileDropDownLabel.Text = 'Lanes File';
            app.LanesFileDropDown = uidropdown(app.LanesTab);
            app.LanesFileDropDown.ValueChangedFcn = createCallbackFcn(app, @LanesFileDropDownValueChanged, true);
            app.PlotModeButtonGroup = uibuttongroup(app.LanesTab);
            app.PlotModeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PlotModeButtonGroupSelectionChanged, true);
            app.PlotModeButtonGroup.Title = 'Plot Mode';
            app.QuiverButton = uiradiobutton(app.PlotModeButtonGroup);
            app.QuiverButton.Text = 'Quiver';
            app.DigraphButton = uiradiobutton(app.PlotModeButtonGroup);
            app.DigraphButton.Text = 'Digraph';
            app.DigraphButton.Value = true;
            app.SaveLanesButton = uibutton(app.LanesTab, 'push');
            app.SaveLanesButton.ButtonPushedFcn = createCallbackFcn(app, @SaveLanesButtonPushed, true);
            app.SaveLanesButton.Text = 'Save';
            app.SaveAsButton = uibutton(app.LanesTab, 'push');
            app.SaveAsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveAsButtonPushed, true);
            app.SaveAsButton.Text = 'Save As';
            app.NewLanesButton = uibutton(app.LanesTab, 'push');
            app.NewLanesButton.ButtonPushedFcn = createCallbackFcn(app, @NewLanesButtonPushed, true);
            app.NewLanesButton.Text = 'New';
            app.PerceptsTab = uitab(app.TabGroup);
            app.PerceptsTab.Title = 'Percepts';
            app.PerceptClassDropDownLabel = uilabel(app.PerceptsTab);
            app.PerceptClassDropDownLabel.Text = 'Percept Class';
            app.PerceptClassDropDown = uidropdown(app.PerceptsTab);
            app.PerceptClassDropDown.ValueChangedFcn = createCallbackFcn(app, @PerceptClassDropDownValueChanged, true);
            app.btn_addPercept = uibutton(app.PerceptsTab, 'push');
            app.btn_addPercept.Tooltip = {'Create a new agent'};
            app.btn_addPercept.Text = '+';
            app.EditPerceptClassButton = uibutton(app.PerceptsTab, 'push');
            app.EditPerceptClassButton.ButtonPushedFcn = createCallbackFcn(app, @EditPerceptClassButtonPushed, true);
            app.EditPerceptClassButton.Text = 'Edit Percept Class';
            app.btn_refreshUAS_2 = uibutton(app.PerceptsTab, 'push');
            app.btn_refreshUAS_2.Icon = 'icons8-refresh-24.png';
            app.btn_refreshUAS_2.Tooltip = {'Refresh the content in this tab'};
            app.btn_refreshUAS_2.Text = '';
            app.tbl_PerceptProps = uitable(app.PerceptsTab);
            app.tbl_PerceptProps.ColumnName = {'Property'};
            app.tbl_PerceptProps.RowName = {};
            app.tbl_PerceptProps.CellSelectionCallback = createCallbackFcn(app, @tbl_PerceptPropsCellSelection, true);
            app.tbl_PerceptMethods = uitable(app.PerceptsTab);
            app.tbl_PerceptMethods.ColumnName = {'Method'};
            app.tbl_PerceptMethods.RowName = {};
            app.tbl_PerceptMethods.CellSelectionCallback = createCallbackFcn(app, @tbl_PerceptMethodsCellSelection, true);
            app.PerceptPropertiesLabel = uilabel(app.PerceptsTab);
            app.PerceptPropertiesLabel.Text = 'Percept Properties';
            app.PerceptMethodsLabel = uilabel(app.PerceptsTab);
            app.PerceptMethodsLabel.Text = 'Percept Methods';
            app.txt_PerceptPropHelp = uitextarea(app.PerceptsTab);
            app.txt_PerceptPropHelp.Editable = 'off';
            app.txt_PerceptMethodHelp = uitextarea(app.PerceptsTab);
            app.txt_PerceptMethodHelp.Editable = 'off';
            app.ActionsTab = uitab(app.TabGroup);
            app.ActionsTab.Title = 'Actions';
            app.ActionClassDropDownLabel = uilabel(app.ActionsTab);
            app.ActionClassDropDownLabel.Text = 'Action Class';
            app.ActionClassDropDown = uidropdown(app.ActionsTab);
            app.ActionClassDropDown.ValueChangedFcn = createCallbackFcn(app, @ActionClassDropDownValueChanged, true);
            app.btn_addAction = uibutton(app.ActionsTab, 'push');
            app.btn_addAction.Tooltip = {'Create a new agent'};
            app.btn_addAction.Text = '+';
            app.tbl_ActionProps = uitable(app.ActionsTab);
            app.tbl_ActionProps.ColumnName = {'Property'};
            app.tbl_ActionProps.RowName = {};
            app.tbl_ActionProps.CellSelectionCallback = createCallbackFcn(app, @tbl_ActionPropsCellSelection, true);
            app.tbl_ActionMethods = uitable(app.ActionsTab);
            app.tbl_ActionMethods.ColumnName = {'Method'};
            app.tbl_ActionMethods.RowName = {};
            app.tbl_ActionMethods.CellSelectionCallback = createCallbackFcn(app, @tbl_ActionMethodsCellSelection, true);
            app.ActionPropertiesLabel_2 = uilabel(app.ActionsTab);
            app.ActionPropertiesLabel_2.Text = 'Action Properties';
            app.ActionMethodsLabel = uilabel(app.ActionsTab);
            app.ActionMethodsLabel.Text = 'Action Methods';
            app.btn_EditActionClass = uibutton(app.ActionsTab, 'push');
            app.btn_EditActionClass.ButtonPushedFcn = createCallbackFcn(app, @btn_EditActionClassButtonPushed, true);
            app.btn_EditActionClass.Text = 'Edit Action Class';
            app.btn_refreshActionClasses = uibutton(app.ActionsTab, 'push');
            app.btn_refreshActionClasses.Icon = 'icons8-refresh-24.png';
            app.btn_refreshActionClasses.Tooltip = {'Refresh the content in this tab'};
            app.btn_refreshActionClasses.Text = '';
            app.txt_ActionPropHelp = uitextarea(app.ActionsTab);
            app.txt_ActionPropHelp.Editable = 'off';
            app.txt_ActionMethodHelp = uitextarea(app.ActionsTab);
            app.txt_ActionMethodHelp.Editable = 'off';
            app.CenterPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.CenterPanel);
            title(app.UIAxes, 'Environment')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes.XLim = [-5 60];
            app.UIAxes.YLim = [-10 10];
            app.UIAxes.ZLim = [0 1];
            app.RightPanel = uipanel(app.GridLayout);
            app.SimulationParametersPanel = uipanel(app.RightPanel);
            app.SimulationParametersPanel.Title = 'Simulation Parameters';
            app.UTMTypeEditFieldLabel = uilabel(app.SimulationParametersPanel);
            app.UTMTypeEditFieldLabel.Text = 'UTM Type:';
            app.UTMTypeEditField = uieditfield(app.SimulationParametersPanel, 'text');
            app.UTMTypeEditField.Editable = 'off';
            app.PerceptTypeEditFieldLabel = uilabel(app.SimulationParametersPanel);
            app.PerceptTypeEditFieldLabel.Text = 'Percept Type:';
            app.PerceptTypeEditField = uieditfield(app.SimulationParametersPanel, 'text');
            app.PerceptTypeEditField.Editable = 'off';
            app.ActionTypeEditFieldLabel = uilabel(app.SimulationParametersPanel);
            app.ActionTypeEditFieldLabel.Text = 'Action Type:';
            app.ActionTypeEditField = uieditfield(app.SimulationParametersPanel, 'text');
            app.ActionTypeEditField.Editable = 'off';
            app.SimulationControlPanel = uipanel(app.RightPanel);
            app.SimulationControlPanel.Title = 'Simulation Control';
            app.RunButton = uibutton(app.SimulationControlPanel, 'state');
            app.RunButton.ValueChangedFcn = createCallbackFcn(app, @RunButtonValueChanged, true);
            app.RunButton.Enable = 'off';
            app.RunButton.Text = 'Run';
            app.InitializeButton = uibutton(app.SimulationControlPanel, 'push');
            app.InitializeButton.ButtonPushedFcn = createCallbackFcn(app, @InitializeButtonPushed, true);
            app.InitializeButton.Text = 'Initialize';
            app.StepsEditFieldLabel = uilabel(app.SimulationControlPanel);
            app.StepsEditFieldLabel.Text = 'Steps:';
            app.StepsEditField = uieditfield(app.SimulationControlPanel, 'numeric');
            app.StepsEditField.Limits = [0 Inf];
            app.StepsEditField.RoundFractionalValues = 'on';
            app.StepsEditField.ValueChangedFcn = createCallbackFcn(app, @StepsEditFieldValueChanged, true);
            app.StepsEditField.Enable = 'off';
            app.StepLabel = uilabel(app.SimulationControlPanel);
            app.StepLabel.Text = 'Step:';
            app.StepEditField = uieditfield(app.SimulationControlPanel, 'numeric');
            app.StepEditField.Editable = 'off';
            app.TimeLabel = uilabel(app.SimulationControlPanel);
            app.TimeLabel.Text = 'Time:';
            app.TimeEditField_h = uieditfield(app.SimulationControlPanel, 'numeric');
            app.TimeEditField_h.Editable = 'off';
            app.TimeEditField_m = uieditfield(app.SimulationControlPanel, 'numeric');
            app.TimeEditField_m.Editable = 'off';
            app.TimeEditField_s = uieditfield(app.SimulationControlPanel, 'numeric');
            app.TimeEditField_s.Editable = 'off';
            app.hLabel = uilabel(app.SimulationControlPanel);
            app.hLabel.Text = 'h';
            app.mLabel = uilabel(app.SimulationControlPanel);
            app.mLabel.Text = 'm';
            app.sLabel = uilabel(app.SimulationControlPanel);
            app.sLabel.Text = 's';
            app.Label = uilabel(app.SimulationControlPanel);
            app.Label.Text = '';
            app.Lamp = uilamp(app.SimulationControlPanel);
            app.ExportSimRunnerButton = uibutton(app.SimulationControlPanel, 'push');
            app.ExportSimRunnerButton.ButtonPushedFcn = createCallbackFcn(app, @ExportSimRunnerButtonPushed, true);
            app.ExportSimRunnerButton.Enable = 'off';
            app.ExportSimRunnerButton.Tooltip = {'Export the simulation object to the workspace inside variable "sim_runner"'};
            app.ExportSimRunnerButton.Text = 'Export Sim Runner';
            app.SeedEditFieldLabel = uilabel(app.SimulationControlPanel);
            app.SeedEditFieldLabel.Enable = 'off';
            app.SeedEditFieldLabel.Text = 'Seed:';
            app.SeedEditField = uieditfield(app.SimulationControlPanel, 'numeric');
            app.SeedEditField.Limits = [0 Inf];
            app.SeedEditField.RoundFractionalValues = 'on';
            app.SeedEditField.ValueDisplayFormat = '%.0f';
            app.SeedEditField.ValueChangedFcn = createCallbackFcn(app, @SeedEditFieldValueChanged, true);
            app.SeedEditField.Enable = 'off';
            app.SimDelaysSliderLabel = uilabel(app.SimulationControlPanel);
            app.SimDelaysSliderLabel.Text = 'Sim Delay (s)';
            app.SimDelaysSlider = uislider(app.SimulationControlPanel);
            app.SimDelaysSlider.Limits = [0 1];
            app.SimDelaysSlider.ValueChangedFcn = createCallbackFcn(app, @SimDelaysSliderValueChanged, true);
            app.SimDelaysSlider.MinorTicks = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.SimDelaysSlider.Enable = 'off';
            % Show the figure after all components are created
            app.URBANUTMUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = urban_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.URBANUTMUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.URBANUTMUIFigure)
        end
    end
end